classdef viewer4Dflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        viewer4DflowUIFigure      matlab.ui.Figure
        ExportGIF                 matlab.ui.control.Button
        RotateY                   matlab.ui.control.Button
        RotateX                   matlab.ui.control.Button
        RotateZ                   matlab.ui.control.Button
        FlipY                     matlab.ui.control.Button
        FlipX                     matlab.ui.control.Button
        FlipZ                     matlab.ui.control.Button
        XSlider                   matlab.ui.control.Slider
        YSlider                   matlab.ui.control.Slider
        ZSlider                   matlab.ui.control.Slider
        TextArea                  matlab.ui.control.TextArea
        XLabel                    matlab.ui.control.Label
        ZLabel                    matlab.ui.control.Label
        YLabel                    matlab.ui.control.Label
        IntervalEditField         matlab.ui.control.NumericEditField
        IntervalmsEditFieldLabel  matlab.ui.control.Label
        PlayButton                matlab.ui.control.Button
        AutoWLButton              matlab.ui.control.Button
        TimeResButtonGroup        matlab.ui.container.ButtonGroup
        TimeResolvedButton        matlab.ui.control.RadioButton
        TimeAveragedButton        matlab.ui.control.RadioButton
        EncodeDropDown            matlab.ui.control.DropDown
        EncodeDropDownLabel       matlab.ui.control.Label
        LoadDataButton            matlab.ui.control.Button
        WindowEditField           matlab.ui.control.NumericEditField
        TimeSlider                matlab.ui.control.Slider
        TimeFrameLabel            matlab.ui.control.Label
        WindowLabel               matlab.ui.control.Label
        LevelEditField            matlab.ui.control.NumericEditField
        LevelLabel                matlab.ui.control.Label
        axZ                       matlab.ui.control.UIAxes
        axY                       matlab.ui.control.UIAxes
        axX                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dataLoaded = false;
        struct_loaded = false;
        dataFormat
        imageData
        currData
        currEncode = "MAG"
        currTime = "AVG";
        xSlice = 1;
        ySlice = 1;
        zSlice = 1;
        frames
        tframe = 1;
        xres
        yres
        zres
        clims
        window
        level
        flipX = false;
        flipY = false;
        flipZ = false;
        rotX = 0;
        rotY = 0;
        rotZ = 0;
        playTimer
        tinterval = 100;
        mag_vel_switch = false;
        encode_list = [];
        path;
        timeres = true;
    end
    
    methods (Access = private)

        function load_rad(app, filepath)
            fid = fopen(fullfile(filepath, 'pcvipr_header.txt'), 'r');
            dataArray = textscan(fid, '%s %s', 'Delimiter', ' \t', 'MultipleDelimsAsOne', true);
            fclose(fid);
            keys = dataArray{1};
            vals = dataArray{2};
        
            for k = 1:length(vals)
                num = str2double(vals{k});
                if ~isnan(num)
                    vals{k} = num;
                end
            end
        
            pcviprHeader = cell2struct(vals, keys, 1);

            resx = pcviprHeader.matrixx;
            resy = pcviprHeader.matrixy; 
            resz = pcviprHeader.matrixz; 
            nframes = pcviprHeader.frames; 
            
            MAG = load_dat_file(app, fullfile(filepath,'MAG.dat'),[resx resy resz]); 
            app.encode_list = [app.encode_list "Magnitude"];
            log(app, 'Loading time averaged data...');
            try
                CD = load_dat_file(app, fullfile(filepath,'CD.dat'),[resx resy resz]); 
                app.encode_list = [app.encode_list "Complex Difference"];
            catch
                disp("No Complex Difference data found")
            end
            try
                VEL1 = load_dat_file(app, fullfile(filepath,'comp_vd_1.dat'),[resx resy resz]); 
                app.encode_list = [app.encode_list "Velocity 1"];
            catch
                disp("No Velocity 1 data found")
            end
            try
                VEL2 = load_dat_file(app, fullfile(filepath,'comp_vd_2.dat'),[resx resy resz]);
                app.encode_list = [app.encode_list "Velocity 2"];
            catch
                disp("No Velocity 2 data found")
            end
            try
                VEL3 = load_dat_file(app, fullfile(filepath,'comp_vd_3.dat'),[resx resy resz]); 
                app.encode_list = [app.encode_list "Velocity 3"];
            catch
                disp("No Velocity 3 data found")
            end
            
            app.imageData.header = pcviprHeader;
            app.imageData.MAG = MAG;
            app.imageData.CD = CD;
            app.imageData.VEL1 = VEL1;
            app.imageData.VEL2 = VEL2;
            app.imageData.VEL3 = VEL3;

            if nframes > 1 && app.timeres
                mag = zeros(resx,resy,resz, nframes); 
                cd = zeros(resx,resy,resz, nframes); 
                v1 = zeros(resx,resy,resz, nframes); 
                v2 = zeros(resx,resy,resz, nframes); 
                v3 = zeros(resx,resy,resz, nframes);
                log(app, sprintf('Loading time frame 1/%d...', nframes));
                for j = 1:nframes
                    if j ~= 1
                        updateLog(app, sprintf('Loading time frame %d/%d...', j, nframes));
                    end
                    mag(:,:,:,j) = load_dat_file(app, fullfile(filepath,[filesep 'ph_' num2str(j-1,'%03i') '_mag.dat']), [resx resy resz]);
                    if any(strcmp("Complex Difference", app.encode_list))
                        cd(:,:,:,j) = load_dat_file(app, fullfile(filepath,[filesep 'ph_' num2str(j-1,'%03i') '_cd.dat']), [resx resy resz]);
                    end
                    if any(strcmp("Velocity 1", app.encode_list))
                        v1(:,:,:,j) = load_dat_file(app, fullfile(filepath,[filesep 'ph_' num2str(j-1,'%03i') '_vd_1.dat']), [resx resy resz]);
                    end
                    if any(strcmp("Velocity 2", app.encode_list))
                        v2(:,:,:,j) = load_dat_file(app, fullfile(filepath,[filesep 'ph_' num2str(j-1,'%03i') '_vd_2.dat']), [resx resy resz]);
                    end
                    if any(strcmp("Velocity 3", app.encode_list))
                        v3(:,:,:,j) = load_dat_file(app, fullfile(filepath,[filesep 'ph_' num2str(j-1,'%03i') '_vd_3.dat']), [resx resy resz]);
                    end
                end
                app.imageData.mag = mag;
                app.imageData.cd = cd;
                app.imageData.vel1 = v1;
                app.imageData.vel2 = v2;
                app.imageData.vel3 = v3;
            end
        end

        function load_h5(app, filepath)
            info = h5info(filepath);
            header = {};
            for i=1:length(info.Groups(2).Attributes)
                attrName = info.Groups(2).Attributes(i).Name;
                attrValue = h5readatt(filepath, '/Header', attrName);
                if info.Groups(2).Attributes(i).Datatype.Class == "H5T_STRING"
                    attrValue = string(regexprep(char(attrValue(:)).','[\x00-\x1F\x7F-\xFF].*',''));  % clean junk characters
                end
                header.(matlab.lang.makeValidName(attrName)) = attrValue;
            end
            nframes = header.frames;

            % python and matlab will flip data ordering so check if size
            % mismatch
            permute_order = [1 2 3];
            shape = h5info(filepath, "/Data/MAG").Dataspace.Size;
            if ~isequal(shape, [header.matrixx header.matrixy header.matrixz])
                permute_order = [3 2 1];
            end
            
            app.imageData.header = header;

            log(app, 'Loading time averaged data...');
            app.imageData.MAG = permute(h5read(filepath, "/Data/MAG"), permute_order);
            app.encode_list = [app.encode_list "Magnitude"];
            try
                app.imageData.CD = permute(h5read(filepath, "/Data/CD"), permute_order);
                app.encode_list = [app.encode_list "Complex Difference"];
            catch
                disp("No Complex Difference data found")
            end
            try
                app.imageData.VEL1 = permute(h5read(filepath, "/Data/comp_vd_1"), permute_order);
                app.encode_list = [app.encode_list "Velocity 1"];
            catch
                disp("No Velocity 1 data found")
            end
            try
                app.imageData.VEL2 = permute(h5read(filepath, "/Data/comp_vd_2"), permute_order);
                app.encode_list = [app.encode_list "Velocity 2"];
            catch
                disp("No Velocity 2 data found")
            end
            try
                app.imageData.VEL3 = permute(h5read(filepath, "/Data/comp_vd_3"), permute_order);
                app.encode_list = [app.encode_list "Velocity 3"];
            catch
                disp("No Velocity 3 data found")
            end
            
            if nframes > 1 && app.timeres
                mag = zeros(header.matrixx, header.matrixy, header.matrixz, nframes);
                cd = zeros(header.matrixx, header.matrixy, header.matrixz, nframes);
                vel1 = zeros(header.matrixx, header.matrixy, header.matrixz, nframes);
                vel2 = zeros(header.matrixx, header.matrixy, header.matrixz, nframes);
                vel3 = zeros(header.matrixx, header.matrixy, header.matrixz, nframes);
                log(app, sprintf('Loading time frame 1/%d...', nframes));
                for i=1:header.frames
                    if i ~= 1
                        updateLog(app, sprintf('Loading time frame %d/%d...', i, nframes));
                    end
                    mag(:,:,:,i) = permute(h5read(filepath, sprintf("/Data/ph_%03i_mag", i-1)), permute_order);
                    if any(strcmp("Complex Difference", app.encode_list))
                        cd(:,:,:,i) = permute(h5read(filepath, sprintf("/Data/ph_%03i_cd", i-1)), permute_order);
                    end
                    if any(strcmp("Velocity 1", app.encode_list))
                        vel1(:,:,:,i) = permute(h5read(filepath, sprintf("/Data/ph_%03i_vd_1", i-1)), permute_order);
                    end
                    if any(strcmp("Velocity 2", app.encode_list))
                        vel2(:,:,:,i) = permute(h5read(filepath, sprintf("/Data/ph_%03i_vd_2", i-1)), permute_order);
                    end
                    if any(strcmp("Velocity 3", app.encode_list))
                        vel3(:,:,:,i) = permute(h5read(filepath, sprintf("/Data/ph_%03i_vd_3", i-1)), permute_order);
                    end
                end
                
                app.imageData.mag = mag;
                app.imageData.cd = cd;
                app.imageData.vel1 = vel1;
                app.imageData.vel2 = vel2;
                app.imageData.vel3 = vel3;
            end
        end

        function load_dcm(app, filepath)
            dirFiles = dir(fullfile(filepath, '*.dcm'));
            if isempty(dirFiles)
                error("No dicom files found")
            end
            mr_info = dicominfo(fullfile(filepath, dirFiles(1).name));
            
            
            if mr_info.MediaStorageSOPClassUID == "1.2.840.10008.5.1.4.1.1.4.1"  % enhanced dicom
                if isscalar(dirFiles)
                    log(app, 'Loading file...');
                    dicom_data = load_enhanced_dicom(app, fullfile(filepath, dirFiles(1).name));
                    if dicom_data.header.frames > 1
                        app.imageData.mag = dicom_data.image;
                        app.imageData.MAG = int16(mean(dicom_data.image, 4));
                        app.encode_list = [app.encode_list "Magnitude"];
                    else
                        app.imageData.MAG = dicom_data.image;
                        app.encode_list = [app.encode_list "Magnitude"];
                    end
                    app.imageData.header = dicom_data.header;
                else
                    log(app, sprintf('Loading file 1/%d...', length(dirFiles)));
                    for i=1:length(dirFiles)
                        if i ~= 1
                            updateLog(app, sprintf('Loading file %d/%d...', i, length(dirFiles)));
                        end
                        mr_info = dicominfo(fullfile(filepath, dirFiles(i).name));
                        dicom_data = load_enhanced_dicom(app, fullfile(filepath, dirFiles(i).name));
                        tframes = dicom_data.header.frames;
                        switch mr_info.Private_0043_1030 % vas collapse (flow encode)
                            case 2
                                if tframes > 1
                                    app.imageData.mag = dicom_data.image;
                                else
                                    app.imageData.MAG = dicom_data.image;
                                end
                                if ~any(strcmp("Magnitude", app.encode_list))
                                    app.encode_list = [app.encode_list "Magnitude"];
                                end
                            case 1 
                                if tframes > 1
                                    app.imageData.cd = dicom_data.image;
                                else
                                    app.imageData.CD = dicom_data.image;
                                end
                                if ~any(strcmp("Complex Difference", app.encode_list))
                                    app.encode_list = [app.encode_list "Complex Difference"];
                                end
                            case 3
                                if tframes > 1
                                    app.imageData.vel1 = dicom_data.image;
                                else
                                    app.imageData.VEL1 = dicom_data.image;
                                end
                                if ~any(strcmp("Velocity 1", app.encode_list))
                                    app.encode_list = [app.encode_list "Velocity 1"];
                                end
                            case 4
                                if tframes > 1
                                    app.imageData.vel2 = dicom_data.image;
                                else
                                    app.imageData.VEL2 = dicom_data.image;
                                end
                                if ~any(strcmp("Velocity 2", app.encode_list))
                                    app.encode_list = [app.encode_list "Velocity 2"];
                                end
                            case 5
                                if tframes > 1
                                    app.imageData.vel3 = dicom_data.image;
                                else
                                    app.imageData.VEL3 = dicom_data.image;
                                end
                                if ~any(strcmp("Velocity 3", app.encode_list))
                                    app.encode_list = [app.encode_list "Velocity 3"];
                                end
                            otherwise
                                if tframes > 1
                                    app.imageData.mag = dicom_data.image;
                                else
                                    app.imageData.MAG = dicom_data.image;
                                end
                                if ~any(strcmp("Magnitude", app.encode_list))
                                    app.encode_list = [app.encode_list "Magnitude"];
                                end
                        end
                        app.imageData.header = dicom_data.header;
                    end
                    if ~isfield(app.imageData, "MAG") && isfield(app.imageData, "mag")
                        app.imageData.MAG = mean(app.imageData.mag, ndims(app.imageData.mag));
                    end
                end
            else % regular product dicom
                log(app, 'Product sequence DICOMs detected. Load folders separately');

                log(app, 'Select Preview folder...');
                preview_dir = uigetdir(pwd, "Select Preview Folder");
                log(app, 'Select Anatomy folder...');
                anatomy_dir = uigetdir(pwd, "Select Anatomy Folder");
                log(app, 'Select LR Flow folder...');
                lr_dir = uigetdir(pwd, "Select LR Flow Folder");
                log(app, 'Select AP Flow folder...');
                ap_dir = uigetdir(pwd, "Select AP Flow Folder");
                log(app, 'Select SI Flow folder...');
                si_dir = uigetdir(pwd, "Select SI Flow Folder");
                log(app, 'Loading data...');

                app.encode_list = ["Magnitude" "Velocity 1" "Velocity 2" "Velocity 3"];

                dirFiles = dir(fullfile(anatomy_dir, '*.dcm'));
                if isempty(dirFiles)
                    error("No dicom files found")
                end
                
                mr_info = dicominfo(fullfile(anatomy_dir, dirFiles(1).name));
                tframes = mr_info.CardiacNumberOfImages;
                slices = length(dirFiles)/tframes;
                app.imageData.header.matrixx = mr_info.Rows;
                app.imageData.header.matrixy = mr_info.Columns;
                app.imageData.header.matrixz = slices;
                app.imageData.header.frames = tframes;
                app.imageData.header.VENC = mr_info.(dicomlookup('0019', '10CC'));
                app.imageData.header.orientation = mr_info.ImageOrientationPatient;

                preview = dicomreadVolume(preview_dir);
                preview = reshape(preview, [mr_info.Rows, mr_info.Columns, 4, slices]);
                app.imageData.MAG = squeeze(preview(:,:,1,:));
                app.imageData.VEL1 = squeeze(preview(:,:,2,:));
                app.imageData.VEL2 = squeeze(preview(:,:,3,:));
                app.imageData.VEL3 = squeeze(preview(:,:,4,:));

                anatomy = dicomreadVolume(anatomy_dir);
                app.imageData.mag = permute(reshape(anatomy, [mr_info.Rows, mr_info.Columns, tframes, slices]), [1 2 4 3]);

                lr = dicomreadVolume(lr_dir);
                app.imageData.vel1 = permute(reshape(lr, [mr_info.Rows, mr_info.Columns, tframes, slices]), [1 2 4 3]);

                ap = dicomreadVolume(ap_dir);
                app.imageData.vel2 = permute(reshape(ap, [mr_info.Rows, mr_info.Columns, tframes, slices]), [1 2 4 3]);
                
                si = dicomreadVolume(si_dir);
                app.imageData.vel3 = permute(reshape(si, [mr_info.Rows, mr_info.Columns, tframes, slices]), [1 2 4 3]);

            end
        end

        function data = load_enhanced_dicom(~, filepath)
            data = {};
            dicom_info = dicominfo(filepath);
            if isfield(dicom_info, "NumberOfTemporalPositions")
                tframes = dicom_info.NumberOfTemporalPositions;
                slices = round(dicom_info.NumberOfFrames/tframes);
            else
                num_groups = length(fieldnames(dicom_info.PerFrameFunctionalGroupsSequence));
                indexes = dicom_info.PerFrameFunctionalGroupsSequence.(sprintf("Item_%d", num_groups)).FrameContentSequence.Item_1.DimensionIndexValues;
                slices = indexes(1);
                tframes = indexes(2);
            end
            image = reshape(dicomread(filepath), [dicom_info.Rows dicom_info.Columns indexes(2) indexes(1)]);
            data.image = permute(image, [1 2 4 3]);
            data.header.matrixx = double(dicom_info.Rows);
            data.header.matrixy = double(dicom_info.Columns);
            data.header.matrixz = double(slices);
            data.header.frames = double(tframes);
            data.header.VENC = double(dicom_info.(dicomlookup('0019', '10CC')));
            try
                data.header.orientation = dicom_info.SharedFunctionalGroupsSequence.Item_1.PlaneOrientationSequence.Item_1.ImageOrientationPatient;
            catch
                data.header.orientation = dicom_info.ImageOrientationPatient;
            end
        end

        function checkEncode(app)
            switch app.currTime
                case "AVG"
                    switch app.currEncode
                        case "MAG"
                            app.currData = app.imageData.MAG;
                        case "CD"
                            app.currData = app.imageData.CD;
                        case "VEL1"
                            app.currData = app.imageData.VEL1;
                        case "VEL2"
                            app.currData = app.imageData.VEL2;
                        case "VEL3"
                            app.currData = app.imageData.VEL3;
                    end
                case "TRES"
                    switch app.currEncode
                        case "MAG"
                            app.currData = app.imageData.mag;
                        case "CD"
                            app.currData = app.imageData.cd;
                        case "VEL1"
                            app.currData = app.imageData.vel1;
                        case "VEL2"
                            app.currData = app.imageData.vel2;
                        case "VEL3"
                            app.currData = app.imageData.vel3;
                    end
            end
        end
        
        function handleScroll(app, event)
            % Get current mouse position
            mousePos = app.viewer4DflowUIFigure.CurrentPoint;
            axHandles = [app.axZ, app.axY, app.axX];
        
            % Scroll direction
            delta = event.VerticalScrollCount;
        
            % Determine which axis the mouse is over
            for k = 1:numel(axHandles)
                ax = axHandles(k);
                pos = ax.Position;
                if mousePos(1) >= pos(1) && mousePos(1) <= pos(1) + pos(3) && ...
                   mousePos(2) >= pos(2) && mousePos(2) <= pos(2) + pos(4)
        
                    switch ax
                        case app.axX
                            app.xSlice = clamp(app, app.xSlice - delta, [1 app.xres]);
                            app.XSlider.Value = app.xSlice;
                        case app.axY
                            app.ySlice = clamp(app, app.ySlice - delta, [1 app.yres]);
                            app.YSlider.Value = app.ySlice;
                        case app.axZ
                            app.zSlice= clamp(app, app.zSlice - delta, [1 app.zres]);
                            app.ZSlider.Value = app.zSlice;
                    end
                    updateViews(app);
                    return
                end
            end
        end

        function rotate_data(app, axis)
            fields = fieldnames(app.imageData);
            for idx = 1:length(fields)
                vol = app.imageData.(fields{idx});
                if ~isnumeric(vol)
                    continue
                end
                switch axis
                    case 1  % Y-axis: swap X and Z
                        rotated = permute(vol, [1 3 2 4]);
                        rotated = flip(rotated, 2);
                        [app.xres, app.zres] = deal(app.zres, app.xres);
                        [app.xSlice, app.zSlice] = deal(app.zSlice, app.xSlice);
                        [app.XSlider.Value, app.ZSlider.Value] = deal(app.ZSlider.Value, app.XSlider.Value);
                    case 2  % X-axis: swap Y and Z
                        rotated = permute(vol, [3 2 1 4]);
                        rotated = flip(rotated, 1);
                        [app.yres, app.zres] = deal(app.zres, app.yres);
                        [app.ySlice, app.zSlice] = deal(app.zSlice, app.ySlice);
                        [app.YSlider.Value, app.ZSlider.Value] = deal(app.ZSlider.Value, app.YSlider.Value);
                    case 3  % Z-axis: swap X and Y
                        rotated = permute(vol, [2 1 3 4]);
                        rotated = flip(rotated, 1);
                        [app.xres, app.yres] = deal(app.yres, app.xres);
                        [app.xSlice, app.ySlice] = deal(app.ySlice, app.xSlice);
                        [app.XSlider.Value, app.YSlider.Value] = deal(app.ZSlider.Value, app.YSlider.Value);
                end
                app.imageData.(fields{idx}) = rotated;
            end
        end

        function flip_data(app, axis)
            fields = fieldnames(app.imageData);
            for idx = 1:length(fields)
                vol = app.imageData.(fields{idx});
                if ~isnumeric(vol)
                    continue
                end
                app.imageData.(fields{idx}) = flip(vol, axis);
            end
        end

        function pack_data(app, data) % for viewing matrix variable from workspace
            dims = size(data);
            switch ndims(data)
                case 3
                    app.imageData.MAG = data;
                    app.imageData.header.matrixx = dims(1);
                    app.imageData.header.matrixy = dims(2);
                    app.imageData.header.matrixz = dims(3);
                    app.imageData.header.frames = 1;
                case 4
                    app.imageData.mag = data;
                    app.imageData.MAG = mean(data, 4);
                    app.imageData.header.matrixx = dims(1);
                    app.imageData.header.matrixy = dims(2);
                    app.imageData.header.matrixz = dims(3);
                    app.imageData.header.frames = dims(4);
            end
        end

        function export_gif(app, view, filename)
            vol = app.currData;
            for k = 1:app.frames
                switch view
                    case 1
                        vol_img = squeeze(vol(app.ySlice, :, :, k));
                    case 2
                        vol_img = squeeze(vol(:, app.xSlice, :, k));
                    case 3
                        vol_img = squeeze(vol(:, :, app.zSlice, k));
                end
                frame = mat2gray(vol_img, app.clims);
                [imind, cm] = gray2ind(frame, 256);
            
                if k == 1
                    imwrite(imind, cm, filename, 'gif', 'Loopcount', inf, 'DelayTime', app.tinterval/1000);
                else
                    imwrite(imind, cm, filename, 'gif', 'WriteMode', 'append', 'DelayTime', app.tinterval/1000);
                end
            end
        end

        function export_video(app, view, filename)
            vol = app.currData;
            v = VideoWriter(filename, 'MPEG-4');
            v.FrameRate = 1000/app.tinterval;
            v.Quality = 100;
            open(v)
            for k = 1:app.frames
                switch view
                    case 1
                        vol_img = squeeze(vol(app.ySlice, :, :, k));
                    case 2
                        vol_img = squeeze(vol(:, app.xSlice, :, k));
                    case 3
                        vol_img = squeeze(vol(:, :, app.zSlice, k));
                end
                frame = mat2gray(vol_img, app.clims);

                writeVideo(v, uint8(frame * 255))
            end
        end

        function autoWL(app)
            if app.currEncode == "VEL1" || app.currEncode == "VEL2" || app.currEncode == "VEL3"
                app.WindowEditField.Limits = [-inf, inf];
                app.LevelEditField.Limits = [-inf, inf];
            else
                app.WindowEditField.Limits = [0, inf];
                app.LevelEditField.Limits = [0, inf];
            end
            dataMax = double(max(app.currData(:)));
            dataMin = double(min(app.currData(:)));
            win = double(dataMax-dataMin);
            win (win < 1) = 1;
            if win == 0
                win = 1;
            end
            lev = double(dataMin) + (win/2);
            app.WindowEditField.Value = win;
            app.LevelEditField.Value = lev;
            app.window = win;
            app.level = lev;
            updateWL(app)
        end

        function updateWL(app)
            app.clims = [(app.level - app.window/2) (app.level + app.window/2)];         
            app.axX.CLim = app.clims;
            app.axY.CLim = app.clims;       
            app.axZ.CLim = app.clims;
        end

        function playFrame(app)
            currentT = app.tframe;
            nextT = mod(currentT, app.frames) + 1;
            app.tframe = nextT;
            app.TimeSlider.Value = nextT;
            updateViews(app); 
        end

        function log(app, msg)
            val = app.TextArea.Value;
            if isempty(val)
                val = {msg};
            else
                val{end+1} = msg;
            end
            app.TextArea.Value = val;
            drawnow;
            scroll(app.TextArea, 'bottom')
        end

        function updateLog(app, msg)
            val = app.TextArea.Value;
            if isempty(val)
                val = {msg};
            else
                val{end} = msg;  % replace last line
            end
            app.TextArea.Value = val;
            drawnow;
            scroll(app.TextArea, 'bottom')
        end

        function v = load_dat_file(~, filename, res)
            m = memmapfile(filename, 'Format', {'int16', prod(res), 'data'});
            v = single(reshape(m.Data.data, res));
        end

        function v = clamp(~, val, limits)
            v = max(min(val, limits(2)), limits(1));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NameValueArgs)

            arguments
                app
                NameValueArgs.data (1,1) struct = struct()
                NameValueArgs.matrix (:,:,:,:) double = []
                NameValueArgs.filepath (1,1) string = ""
                NameValueArgs.tavg (1,1) logical = false
            end

            app.WindowEditField.Enable = "off";
            app.LevelEditField.Enable = "off";
            app.EncodeDropDown.Enable = "off";
            app.TimeResButtonGroup.Enable = "off";
            app.ZSlider.Enable = "off";
            app.YSlider.Enable = "off";
            app.XSlider.Enable = "off";
            app.TimeSlider.Enable = "off";
            app.IntervalEditField.Enable = "off";
            app.PlayButton.Enable = "off";
            

            if isfield(NameValueArgs.data, "header")
                app.imageData = NameValueArgs.data;
                app.struct_loaded = true;
                LoadDataButtonPushed(app, []);
            elseif ~isempty(NameValueArgs.matrix) && numel(size(NameValueArgs.matrix)) > 2
                pack_data(app, NameValueArgs.matrix)
                app.struct_loaded = true;
                LoadDataButtonPushed(app, []);
            else
                app.timeres = ~NameValueArgs.tavg;
                if NameValueArgs.filepath ~= ""
                    app.path = NameValueArgs.filepath;
                    LoadDataButtonPushed(app, []);
                end
            end
            
            app.viewer4DflowUIFigure.WindowScrollWheelFcn = @(src, event) app.handleScroll(event);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            if app.dataLoaded
                cla(app.imageData);
                cla(app.axZ);
                cla(app.axY);
                cla(app.axX);
                cla(app.xres);
                cla(app.yres);
                cla(app.zres);
                cla(app.frames);
                cla(app.dataFormat);
                app.xSlice = 1;
                app.ySlice = 1;
                app.zSlice = 1;
                app.tframe = 1;
                app.flipX = false;
                app.flipY = false;
                app.flipZ = false;
                app.rotX = 0;
                app.rotY = 0;
                app.rotZ = 0;
                app.TimeResButtonGroup.SelectedObject = app.TimeAveragedButton;
                app.EncodeDropDown.Value = "Magnitude";
                app.currEncode = "MAG";
                app.currTime = "AVG";
                app.encode_list = [];
                app.ZSlider.Value = 1;
                app.YSlider.Value = 1;
                app.XSlider.Value = 1;
                app.TimeSlider.Value = 1;
            end

            if app.struct_loaded
                app.dataLoaded = true;
                app.dataFormat = "struct";

                if isfield(app.imageData, "MAG")
                    app.encode_list = [app.encode_list "Magnitude"];
                end
                if isfield(app.imageData, "CD")
                    app.encode_list = [app.encode_list "Complex Difference"];
                end
                if isfield(app.imageData, "VEL1")
                    app.encode_list = [app.encode_list "Velocity 1"];
                end
                if isfield(app.imageData, "VEL2")
                    app.encode_list = [app.encode_list "Velocity 2"];
                end
                if isfield(app.imageData, "VEL3")
                    app.encode_list = [app.encode_list "Velocity 3"];
                end
                log(app, 'Loading data from variable');
            else
                if ~isempty(app.path)
                    [filepath, data_file, fext] = fileparts(app.path);
                else
                    [data_file, filepath] = uigetfile({'*.txt;*.h5;*.dcm'});
                    if data_file == 0
                        return
                    end
                    [~,fname,fext] = fileparts(data_file);
                end

                if isequal(data_file, 0)
                    return
                end
                
                switch lower(fext)
                    case ".txt"
                        app.dataFormat = "rad";
                        if fname == "pcvipr_header"
                            log(app, sprintf('Loading binary data from %s%s ...',filepath, data_file));
                            load_rad(app, filepath);
                        end
                    case ".h5"
                        app.dataFormat = "hdf5";
                        log(app, sprintf('Loading HDF5 data from file %s%s ...', filepath, data_file));
                        load_h5(app, fullfile(filepath, data_file));
                    case ".dcm"
                        app.dataFormat = "dicom";
                        log(app, sprintf('Loading DICOM data from %s ...', filepath));
                        load_dcm(app, filepath);
                end
            end

            app.xres = double(size(app.imageData.MAG, 2));
            app.yres = double(size(app.imageData.MAG, 1));
            app.zres = double(size(app.imageData.MAG, 3));
            app.frames = double(app.imageData.header.frames);
            
            app.WindowEditField.Enable = "on";
            app.LevelEditField.Enable = "on";
            app.EncodeDropDown.Enable = "on";
            if app.frames > 1
                app.TimeResButtonGroup.Enable = "on";
            end
            app.ZSlider.Enable = "on";
            app.YSlider.Enable = "on";
            app.XSlider.Enable = "on";
            app.TimeSlider.Enable = "off";
            app.IntervalEditField.Enable = "off";
            app.PlayButton.Enable = "off";
            app.ExportGIF.Enable = "off";
            app.EncodeDropDown.Items = app.encode_list;

            %% orientation
            % if isfield(app.imageData.header, "orientation") && app.zres ~= 1
            % 
            %     if isstring(app.imageData.header.orientation) || ischar(app.imageData.header.orientation)
            %         app.imageData.header.orientation = str2double(strsplit(app.imageData.header.orientation, "\"))';
            %     end
            % 
            %     x_vec = [app.imageData.header.orientation(1); app.imageData.header.orientation(2); app.imageData.header.orientation(3)];
            %     y_vec = [app.imageData.header.orientation(4); app.imageData.header.orientation(5); app.imageData.header.orientation(6)];
            %     z_vec = cross(x_vec, y_vec);
            %     rot_matrix = [x_vec y_vec z_vec];
            %     tform = rigidtform3d(rot_matrix, [0, 0, 0]);
            % 
            %     % calculate new dimensions
            %     [Xc,Yc,Zc] = ndgrid([1 app.xres], [1 app.yres], [1 app.zres]);
            %     corners = [Xc(:), Yc(:), Zc(:)]';
            % 
            %     rot_corners = rot_matrix * corners;
            % 
            %     min_corner = min(rot_corners, [], 2);
            %     max_corner = max(rot_corners, [], 2);
            % 
            %     app.xres = round(max_corner(1) - min_corner(1)) + 1;
            %     app.yres = round(max_corner(2) - min_corner(2)) + 1;
            %     app.zres = round(max_corner(3) - min_corner(3)) + 1;
            % 
            %     fields = fieldnames(app.imageData);
            %     for i = 1:length(fields)
            %         vol = app.imageData.(fields{i});
            %         if ~isnumeric(vol)
            %             continue
            %         end
            % 
            %         if ndims(vol) == 3
            %             vol = imwarp(vol, tform);
            %         else
            %             temp = zeros(app.yres, app.xres, app.zres);
            %             for j=1:size(vol, 4)
            %                 temp(:,:,:,j) = imwarp(vol(:,:,:,j), tform);
            %             end
            %             vol = temp;
            %         end
            % 
            %         app.imageData.(fields{i}) = vol;
            %     end
            % end
            %
            % if isfield(app.imageData.header, "rotation")
            %     switch app.imageData.header.rotation
            %         case "Rotate90Clockwise"
            %             rotate_data(app, 3)
            %             rotate_data(app, 3)
            %             rotate_data(app, 3)
            %         case "Rotate90CounterClockwise"
            %             rotate_data(app, 3)
            %         case "Rotate180"
            %             rotate_data(app, 3)
            %             rotate_data(app, 3)
            %         case "Rotate270Clockwise"
            %             rotate_data(app, 3)
            %             rotate_data(app, 3)
            %             rotate_data(app, 3)
            %         case "Rotate270CounterClockwise"
            %             rotate_data(app, 3)
            %     end
            % end

            app.currData = app.imageData.MAG;
            
            app.XSlider.Limits = [1 app.xres];
            app.xSlice = round(size(app.imageData.MAG, 2)/2);
            app.XSlider.Value = app.xSlice;
            app.XLabel.Text = sprintf('%d/%d', app.xSlice, app.xres);
            axis(app.axX, 'image')
            
            app.YSlider.Limits = [1 app.yres];
            app.ySlice = round(size(app.imageData.MAG, 1)/2);
            app.YSlider.Value = app.ySlice;
            app.YLabel.Text = sprintf('%d/%d', app.ySlice, app.yres);
            axis(app.axY, 'image')
            
            if app.zres == 1
                app.ZSlider.Limits = [1 2];
                app.ZSlider.Value = 1;
                app.zSlice = 1;
            else
                app.ZSlider.Limits = [1 app.zres];
                app.zSlice = round(size(app.imageData.MAG, 3)/2);
                app.ZSlider.Value = app.zSlice;
            end
            app.ZLabel.Text = sprintf('%d/%d', app.zSlice, app.zres);
            axis(app.axZ, 'image')
            
            if app.frames == 1
                app.TimeSlider.Limits = [1, 2];
                app.TimeSlider.MajorTicks = 1:2;
            else
                app.TimeSlider.Limits = [1, app.frames];
                app.TimeSlider.MajorTicks = 1:app.frames;
            end
            app.TimeSlider.MinorTicks = [];

            log(app, 'Data loaded');

            autoWL(app);
            updateViews(app);
        end

        % Callback function: TimeSlider, axX, axY, axZ
        function updateViews(app, event)
            y_view = squeeze(app.currData(app.ySlice, :, :, app.tframe));
            x_view = squeeze(app.currData(:, app.xSlice, :, app.tframe));
            z_view = squeeze(app.currData(:, :, app.zSlice, app.tframe));
            
            imagesc(app.axY, y_view');
            imagesc(app.axX, x_view');
            imagesc(app.axZ, z_view);
            drawnow limitrate
        end

        % Value changed function: EncodeDropDown
        function EncodeDropDownValueChanged(app, event)
            prev_encode = app.currEncode;
            value = app.EncodeDropDown.Value;
            switch value
                case "Magnitude"
                    app.currEncode = "MAG";
                case "Complex Difference"
                    app.currEncode = "CD";
                case "Velocity 1"
                    app.currEncode = "VEL1";
                case "Velocity 2"
                    app.currEncode = "VEL2";
                case "Velocity 3"
                    app.currEncode = "VEL3";
            end
            
            checkEncode(app)

            if prev_encode == "MAG" || prev_encode == "CD" 
                if app.currEncode == "VEL1" || app.currEncode == "VEL2" || app.currEncode == "VEL3"
                    autoWL(app)
                end
            elseif prev_encode == "VEL1" || prev_encode == "VEL2" || prev_encode == "VEL3"
                if app.currEncode == "MAG" || app.currEncode == "CD"
                    autoWL(app)
                end
            end

            updateViews(app);
        end

        % Selection changed function: TimeResButtonGroup
        function TimeResButtonGroupSelectionChanged(app, event)
            selectedButton = app.TimeResButtonGroup.SelectedObject.Text;
            switch selectedButton
                case "Time Averaged"
                    app.currTime = "AVG";
                    app.TimeSlider.Value = 1;
                    app.TimeSlider.Enable = "off";
                    app.IntervalEditField.Enable = "off";
                    app.ExportGIF.Enable = "off";
                    app.PlayButton.Enable = "off";
                    app.tframe = 1;
                    if ~isempty(app.playTimer) && isvalid(app.playTimer)
                        if strcmp(app.playTimer.Running, 'on')
                            stop(app.playTimer);
                            delete(app.playTimer);
                            app.PlayButton.Text = '▶';
                        end
                    end
                case "Time Resolved"
                    app.currTime = "TRES";
                    app.ExportGIF.Enable = "on";
                    app.TimeSlider.Enable = "on";
                    app.IntervalEditField.Enable = "on";
                    app.PlayButton.Enable = "on";
            end

            checkEncode(app)
            updateViews(app);
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            app.tframe = clamp(app, round(event.Value), [1, app.frames]);
            updateViews(app);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if isempty(app.playTimer) || ~isvalid(app.playTimer)
                % Create a new timer
                app.playTimer = timer(...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', (app.tinterval/1000), ...          % Adjust speed here (0.1 = 10 FPS)
                    'TimerFcn', @(~,~) playFrame(app), ...
                    'BusyMode', 'drop');        % Skip frames if overloaded
            end
        
            if strcmp(app.playTimer.Running, 'off')
                start(app.playTimer);
                app.PlayButton.Text = '⏸';
            else
                stop(app.playTimer);
                delete(app.playTimer);
                app.PlayButton.Text = '▶';
            end
        end

        % Value changed function: IntervalEditField
        function IntervalEditFieldValueChanged(app, event)
            app.tinterval = app.IntervalEditField.Value;

            if app.tinterval <= 0
                uialert(app.viewer4DflowUIFigure, 'Time interval must be positive.', 'Invalid Input');
                return
            end
        
            % If the timer exists and is valid
            if ~isempty(app.playTimer) && isvalid(app.playTimer)
                wasRunning = strcmp(app.playTimer.Running, 'on');
                
                % Stop timer if running
                if wasRunning
                    stop(app.playTimer);
                    delete(app.playTimer);
                    app.playTimer = timer(...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', (app.tinterval/1000), ...          % Adjust speed here (0.1 = 10 FPS)
                    'TimerFcn', @(~,~) playFrame(app), ...
                    'BusyMode', 'drop');
                    start(app.playTimer);
                end
            end
        end

        % Value changed function: WindowEditField
        function WindowEditFieldValueChanged(app, event)
            app.window = app.WindowEditField.Value;
            updateWL(app);
        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)
            app.level = app.LevelEditField.Value;
            updateWL(app);
        end

        % Button pushed function: AutoWLButton
        function AutoWLButtonPushed(app, event)
            autoWL(app);
        end

        % Button pushed function: FlipZ
        function FlipZButtonPushed(app, event)
            app.flipZ = ~app.flipZ;
            flip_data(app, 3);
            log(app, 'Flipped Z dimension');
            checkEncode(app);
            updateViews(app);
        end

        % Button pushed function: RotateZ
        function RotateZButtonPushed(app, event)
            if app.rotZ == 270
                app.rotZ = 0;
            else
                app.rotZ = app.rotZ + 90;
            end
            log(app, 'Rotating 90° around Z...');
            rotate_data(app, 3);
            app.ZLabel.Text = sprintf('%d/%d', app.zSlice, app.zres);
            checkEncode(app);
            updateViews(app);
        end

        % Button pushed function: FlipY
        function FlipYButtonPushed(app, event)
            app.flipY = ~app.flipY;
            flip_data(app, 1);    % matlab uses columns as first dim
            log(app, 'Flipped Y dimension');
            checkEncode(app);
            updateViews(app);
        end

        % Button pushed function: RotateY
        function RotateYButtonPushed(app, event)
            if app.rotY == 270
                app.rotY = 0;
            else
                app.rotY = app.rotY + 90;
            end
            log(app, 'Rotating 90° around Y...');
            rotate_data(app, 1);
            app.YLabel.Text = sprintf('%d/%d', app.ySlice, app.yres);
            checkEncode(app);
            updateViews(app);
        end

        % Button pushed function: FlipX
        function FlipXButtonPushed(app, event)
            app.flipX = ~app.flipX;
            flip_data(app, 2);    % matlab uses rows as second dim
            log(app, 'Flipped X dimension');
            checkEncode(app);
            updateViews(app);
        end

        % Button pushed function: RotateX
        function RotateXButtonPushed(app, event)
            if app.rotX == 270
                app.rotX = 0;
            else
                app.rotX = app.rotX + 90;
            end
            log(app, 'Rotating 90° around X...');
            rotate_data(app, 2);
            app.XLabel.Text = sprintf('%d/%d', app.xSlice, app.xres);
            checkEncode(app);
            updateViews(app);
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            app.zSlice = clamp(app, round(event.Value), [1, app.zres]);
            app.ZLabel.Text = sprintf('%d/%d', app.zSlice, app.zres);
            checkEncode(app);
            updateViews(app);
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            app.ySlice = clamp(app, round(event.Value), [1, app.yres]);
            app.YLabel.Text = sprintf('%d/%d', app.ySlice, app.yres);
            checkEncode(app);
            updateViews(app);
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            app.xSlice = clamp(app, round(event.Value), [1, app.xres]);
            app.XLabel.Text = sprintf('%d/%d', app.xSlice, app.xres);
            checkEncode(app);
            updateViews(app);
        end

        % Button pushed function: ExportGIF
        function ExportGIFButtonPushed(app, event)
            % Create dialog for checkbox selection
            d = dialog('Position',[300 300 250 200], 'Name','Export views');
        
            cb1 = uicontrol(d, 'Style','checkbox', 'String','X', ...
                'Position',[100 140 200 20], 'Value',1);
            cb2 = uicontrol(d, 'Style','checkbox', 'String','Y', ...
                'Position',[100 110 200 20], 'Value',1);
            cb3 = uicontrol(d, 'Style','checkbox', 'String','Z', ...
                'Position',[100 80 200 20], 'Value',1);
        
            uicontrol(d, 'Style','pushbutton', 'String','OK', ...
                'Position',[40 20 70 25], ...
                'Callback', @(~,~) uiresume(d));
        
            uicontrol(d, 'Style','pushbutton', 'String','Cancel', ...
                'Position',[130 20 70 25], ...
                'Callback', @(~,~) (delete(d)));
        
            uiwait(d);
        
            if ~isvalid(d)
                return;
            end
        
            selections = [cb1.Value, cb2.Value, cb3.Value];
            delete(d);
        
            [file, fpath] = uiputfile( ...
                {'*.mp4', 'MP4 (*.mp4)'; ...
                 '*.gif', 'GIF (*.gif)'}, ...
                'Save Animation As');

            if isequal(file,0)
                return;
            end

            [~,baseName,ext] = fileparts(file);
            
            names = ["X", "Y", "Z"];
            
            for v = 1:3
                if selections(v)
            
                    outFile = fullfile(fpath, ...
                        strcat(baseName, '_view', names(v), ext));
            
                    switch ext
                        case '.gif'
                            export_gif(app, v, outFile)
            
                        case '.mp4'
                            export_video(app, v, outFile)
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.viewer4DflowUIFigure = uifigure('Visible', 'off');
            colormap(app.viewer4DflowUIFigure, 'gray');
            app.viewer4DflowUIFigure.Name = 'viewer4Dflow';
            app.axX = uiaxes(app.viewer4DflowUIFigure);
            app.axX.XLimitMethod = 'padded';
            app.axX.YLimitMethod = 'padded';
            app.axX.ZLimitMethod = 'padded';
            app.axX.XTick = [];
            app.axX.YTick = [];
            app.axX.ZTick = [];
            app.axX.ButtonDownFcn = createCallbackFcn(app, @updateViews, true);
            colormap(app.axX, 'gray')
            app.axY = uiaxes(app.viewer4DflowUIFigure);
            app.axY.XLimitMethod = 'padded';
            app.axY.YLimitMethod = 'padded';
            app.axY.ZLimitMethod = 'padded';
            app.axY.XTick = [];
            app.axY.YTick = [];
            app.axY.ZTick = [];
            app.axY.ButtonDownFcn = createCallbackFcn(app, @updateViews, true);
            colormap(app.axY, 'gray')
            app.axZ = uiaxes(app.viewer4DflowUIFigure);
            app.axZ.XLimitMethod = 'padded';
            app.axZ.YLimitMethod = 'padded';
            app.axZ.ZLimitMethod = 'padded';
            app.axZ.XTick = [];
            app.axZ.YTick = [];
            app.axZ.ZTick = [];
            app.axZ.ButtonDownFcn = createCallbackFcn(app, @updateViews, true);
            colormap(app.axZ, 'gray')
            app.LevelLabel = uilabel(app.viewer4DflowUIFigure);
            app.LevelLabel.Text = 'Level';
            app.LevelEditField = uieditfield(app.viewer4DflowUIFigure, 'numeric');
            app.LevelEditField.Limits = [-32768 32768];
            app.LevelEditField.ValueDisplayFormat = '%.0f';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.WindowLabel = uilabel(app.viewer4DflowUIFigure);
            app.WindowLabel.Text = 'Window';
            app.TimeFrameLabel = uilabel(app.viewer4DflowUIFigure);
            app.TimeFrameLabel.Text = 'Time Frame';
            app.TimeSlider = uislider(app.viewer4DflowUIFigure);
            app.TimeSlider.Limits = [0 30];
            app.TimeSlider.MajorTicks = [];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @updateViews, true);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.TimeSlider.MinorTicks = [0 30];
            app.TimeSlider.Enable = 'off';
            app.WindowEditField = uieditfield(app.viewer4DflowUIFigure, 'numeric');
            app.WindowEditField.Limits = [-32768 32768];
            app.WindowEditField.ValueDisplayFormat = '%.0f';
            app.WindowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.LoadDataButton = uibutton(app.viewer4DflowUIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.EncodeDropDownLabel = uilabel(app.viewer4DflowUIFigure);
            app.EncodeDropDownLabel.Text = 'Encode';
            app.EncodeDropDown = uidropdown(app.viewer4DflowUIFigure);
            app.EncodeDropDown.Items = {'Magnitude'};
            app.EncodeDropDown.ValueChangedFcn = createCallbackFcn(app, @EncodeDropDownValueChanged, true);
            app.EncodeDropDown.Value = 'Magnitude';
            app.TimeResButtonGroup = uibuttongroup(app.viewer4DflowUIFigure);
            app.TimeResButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TimeResButtonGroupSelectionChanged, true);
            app.TimeAveragedButton = uiradiobutton(app.TimeResButtonGroup);
            app.TimeAveragedButton.Text = 'Time Averaged';
            app.TimeAveragedButton.Value = true;
            app.TimeResolvedButton = uiradiobutton(app.TimeResButtonGroup);
            app.TimeResolvedButton.Text = 'Time Resolved';
            app.AutoWLButton = uibutton(app.viewer4DflowUIFigure, 'push');
            app.AutoWLButton.ButtonPushedFcn = createCallbackFcn(app, @AutoWLButtonPushed, true);
            app.AutoWLButton.Text = 'Auto W/L';
            app.PlayButton = uibutton(app.viewer4DflowUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = '▶';
            app.IntervalmsEditFieldLabel = uilabel(app.viewer4DflowUIFigure);
            app.IntervalmsEditFieldLabel.Text = 'Interval (ms)';
            app.IntervalEditField = uieditfield(app.viewer4DflowUIFigure, 'numeric');
            app.IntervalEditField.ValueDisplayFormat = '%.0f';
            app.IntervalEditField.ValueChangedFcn = createCallbackFcn(app, @IntervalEditFieldValueChanged, true);
            app.IntervalEditField.Value = 100;
            app.YLabel = uilabel(app.viewer4DflowUIFigure);
            app.YLabel.Text = '0/0';
            app.ZLabel = uilabel(app.viewer4DflowUIFigure);
            app.ZLabel.Text = '0/0';
            app.XLabel = uilabel(app.viewer4DflowUIFigure);
            app.XLabel.Text = '0/0';
            app.TextArea = uitextarea(app.viewer4DflowUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Figure initialized'};
            app.ZSlider = uislider(app.viewer4DflowUIFigure);
            app.ZSlider.Limits = [0 256];
            app.ZSlider.MajorTicks = [];
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.ZSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100 104 108 112 116 120 124 128 132 136 140 144 148 152 156 160 164 168 172 176 180 184 188 192 196 200 204 208 212 216 220 224 228 232 236 240 244 248 252 256];
            app.ZSlider.Enable = 'off';
            app.YSlider = uislider(app.viewer4DflowUIFigure);
            app.YSlider.Limits = [0 256];
            app.YSlider.MajorTicks = [];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.YSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100 104 108 112 116 120 124 128 132 136 140 144 148 152 156 160 164 168 172 176 180 184 188 192 196 200 204 208 212 216 220 224 228 232 236 240 244 248 252 256];
            app.YSlider.Enable = 'off';
            app.XSlider = uislider(app.viewer4DflowUIFigure);
            app.XSlider.Limits = [0 256];
            app.XSlider.MajorTicks = [];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.XSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100 104 108 112 116 120 124 128 132 136 140 144 148 152 156 160 164 168 172 176 180 184 188 192 196 200 204 208 212 216 220 224 228 232 236 240 244 248 252 256];
            app.XSlider.Enable = 'off';
            app.FlipZ = uibutton(app.viewer4DflowUIFigure, 'push');
            app.FlipZ.ButtonPushedFcn = createCallbackFcn(app, @FlipZButtonPushed, true);
            app.FlipZ.Text = 'Flip Z';
            app.FlipX = uibutton(app.viewer4DflowUIFigure, 'push');
            app.FlipX.ButtonPushedFcn = createCallbackFcn(app, @FlipXButtonPushed, true);
            app.FlipX.Text = 'Flip X';
            app.FlipY = uibutton(app.viewer4DflowUIFigure, 'push');
            app.FlipY.ButtonPushedFcn = createCallbackFcn(app, @FlipYButtonPushed, true);
            app.FlipY.Text = 'Flip Y';
            app.RotateZ = uibutton(app.viewer4DflowUIFigure, 'push');
            app.RotateZ.ButtonPushedFcn = createCallbackFcn(app, @RotateZButtonPushed, true);
            app.RotateZ.Text = 'Rotate Z';
            app.RotateX = uibutton(app.viewer4DflowUIFigure, 'push');
            app.RotateX.ButtonPushedFcn = createCallbackFcn(app, @RotateXButtonPushed, true);
            app.RotateX.Text = 'Rotate X';
            app.RotateY = uibutton(app.viewer4DflowUIFigure, 'push');
            app.RotateY.ButtonPushedFcn = createCallbackFcn(app, @RotateYButtonPushed, true);
            app.RotateY.Text = 'Rotate Y';
            app.ExportGIF = uibutton(app.viewer4DflowUIFigure, 'push');
            app.ExportGIF.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.ExportGIF.Text = 'Export Animation';
            % Show the figure after all components are created
            app.viewer4DflowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viewer4Dflow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.viewer4DflowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.viewer4DflowUIFigure)
        end
    end
end